classdef menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        BemVindoTab                    matlab.ui.container.Tab
        Continuar1stPageButton         matlab.ui.control.Button
        LiCienteCheckBox               matlab.ui.control.CheckBox
        ImportanteTextArea             matlab.ui.control.TextArea
        ImportanteLabel                matlab.ui.control.Label
        EscolhaModeloTab               matlab.ui.container.Tab
        VoltarEscolhaModeloButton      matlab.ui.control.Button
        ContinuarEscolhaModeloButton   matlab.ui.control.Button
        BreveExplicacaoModeloTextArea  matlab.ui.control.TextArea
        BreveexplicaoTextAreaLabel     matlab.ui.control.Label
        EscolhaModeloDropDown          matlab.ui.control.DropDown
        EscolhaoexemploaseranalisadoDropDownLabel  matlab.ui.control.Label
        EscolhaFiltroTab               matlab.ui.container.Tab
        EscolhaFiltroDropDown          matlab.ui.control.DropDown
        EscolhadoFiltroLabel           matlab.ui.control.Label
        BreveExplicacaoFiltroTextArea  matlab.ui.control.TextArea
        BreveexplicaoTextAreaLabel_2   matlab.ui.control.Label
        VoltarEscolhaFiltroButton      matlab.ui.control.Button
        ContinuarEscolhaFiltroButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        oLinear =  1; % boolean variable that defines if the model chosed is linear or not
        kalmanfilter_rlc; % object for the kalman filter app call;
        kalmanfilter_smm; %
        extendedkalmanfilter_kst; % object for the extended kalman filter app call;
        unscentedkalmanfilter_kst; % object for the unscented filter app call;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Makes the following Tabs invisible at the beginning
            app.EscolhaModeloTab.Parent = []; 
            app.EscolhaFiltroTab.Parent = [];
        end

        % Button pushed function: Continuar1stPageButton
        function Continuar1stPageButtonPushed(app, event)
            % Check if the check box 'Li e estou ciente' is checked
            % if no, print a message for the user
            if(app.LiCienteCheckBox.Value == 0)
                 waitfor(msgbox("É preciso marcar a opção 'Li e estou ciente'", "modal"));
            % else, it is possible to procced
            else
                % Change to the following Tab
                app.EscolhaModeloTab.Parent=app.TabGroup;
                app.TabGroup.SelectedTab = app.EscolhaModeloTab;
                
                % Disable the botton and the check box disabled
                app.LiCienteCheckBox.Enable = 'off';   
            end
        end

        % Value changed function: EscolhaModeloDropDown
        function EscolhaModeloDropDownValueChanged(app, event)
            % Change the text area every time drop down value changes
            switch (app.EscolhaModeloDropDown.Value)
                case '-'
                    % clear the text area first
                    h=findall(app.BreveExplicacaoModeloTextArea,'Type','UITextArea');
                    for i=1:numel(h)
                        h(i).Value='';
                    end
                    % set the new text to the text area
                    app.BreveExplicacaoModeloTextArea.Value = '-';
                % Comments above are applicable for all cases bolow:
                case 'Circuito RLC'
                    h=findall(app.BreveExplicacaoModeloTextArea,'Type','UITextArea');
                    for i=1:numel(h)
                        h(i).Value='';
                    end
                    app.BreveExplicacaoModeloTextArea.Value = ['Um circuito RLC (também conhecido como circuito ressonante ou circuito aceitador) é '...
                        'um circuito elétrico consistindo de um resistor (R), um indutor (L), e um capacitor (C), '...
                        'conectados em série ou em paralelo. Referência: Richard C. Dorf e Robert H. Bishop, “Modern Control Systems”, 13ª edição, Ex. P3.22, p. 218.'];
                    app.oLinear = 1;
                case 'Sistema Massa-Mola'
                    h=findall(app.BreveExplicacaoModeloTextArea,'Type','UITextArea');
                    for i=1:numel(h)
                        h(i).Value='';
                    end
                    app.BreveExplicacaoModeloTextArea.Value = ['Oscilador massa-mola é um sistema físico composto por uma mola ideal, '...
                        'de constante elástica k, presa a um corpo de massa m. Quando esticada ou comprimida, a mola adquire energia potencial elástica, '...
                        'quando solta, essa energia potencial passa a ser periodicamente convertida em energia cinética.'...
                        'Referência: Norman N. Nise, “Control Systems Engineering”, John Wiley & Sons, Inc., 6ª edição, Ex. 27, p. 101.'];
                    app.oLinear = 1;
                case 'Kinematic Single-Track'
                    h=findall(app.BreveExplicacaoModeloTextArea,'Type','UITextArea');
                    for i=1:numel(h)
                        h(i).Value='';
                    end
                    app.BreveExplicacaoModeloTextArea.Value = ['O modelo Kinematic Single-Track, ou modelo "Bicicleta", ' ...
                    'é uma representação matemática de um veículo de modo simplificado e não linear (nele o veículo é representado com apenas duas rodas, uma dianteira '...
                    ' e uma traseira e seus centros de gravidade unidos pelo eixo de base). As equações do modelo KST são puramente cinemáticas, ou seja, '...
                    ' forças não são consideradas. Referência: "CommonRoad: Composable benchmarks for motion planning on roads", Althoff, Matthias and Koschi, Markus and Manzinger, Stefanie.'];
                    app.oLinear = 0;
            end
        end

        % Button pushed function: ContinuarEscolhaModeloButton
        function ContinuarEscolhaModeloButtonPushed(app, event)
            % Check if some valid model was chosed
            if app.EscolhaModeloDropDown.Value == '-'
              waitfor(msgbox("É preciso escolher um modelo para iniciar a aplicação.", "modal"));  
            else
                % Change to the following Tab
                app.EscolhaFiltroTab.Parent=app.TabGroup;
                app.TabGroup.SelectedTab = app.EscolhaFiltroTab;
            end
        end

        % Button pushed function: VoltarEscolhaModeloButton
        function VoltarEscolhaModeloButtonPushed(app, event)
            % Go back to the 'BemVindoTab'
            app.BemVindoTab.Parent=app.TabGroup;
            app.TabGroup.SelectedTab = app.BemVindoTab;
            % Make the 'EscolhaModeloTab' invisible again
            app.EscolhaModeloTab.Parent = [];
        end

        % Button pushed function: VoltarEscolhaFiltroButton
        function VoltarEscolhaFiltroButtonPushed(app, event)
            % Go back to the 'EscolhaModeloTab'
            app.EscolhaModeloTab.Parent=app.TabGroup;
            app.TabGroup.SelectedTab = app.EscolhaModeloTab;
            % Make the 'EscolhaFiltroTab' invisible again
            app.EscolhaFiltroTab.Parent = [];
        end

        % Value changed function: EscolhaFiltroDropDown
        function EscolhaFiltroDropDownValueChanged(app, event)
            % Change the text area every time drop down value changes
            switch (app.EscolhaFiltroDropDown.Value)
                case '-'
                    % clear the text area first
                    h=findall(app.BreveExplicacaoFiltroTextArea,'Type','UITextArea');
                    for i=1:numel(h)
                        h(i).Value='';
                    end
                    % set the new text to the text area
                    app.BreveExplicacaoFiltroTextArea.Value = '-';
                % Comments above are applicable for all cases bolow:
                case 'KF'
                    h=findall(app.BreveExplicacaoFiltroTextArea,'Type','UITextArea');
                    for i=1:numel(h)
                        h(i).Value='';
                    end
                    app.BreveExplicacaoFiltroTextArea.Value = ['O KF é usado para sistemas '...
                        'lineares com funções discretizadas no tempo.'];
   
                case 'EKF'
                    h=findall(app.BreveExplicacaoFiltroTextArea,'Type','UITextArea');
                    for i=1:numel(h)
                        h(i).Value='';
                    end
                    app.BreveExplicacaoFiltroTextArea.Value = ['o EKF é usado para sistemas ' ...
                    ' não-lineares e com funções discretizadas no tempo.'];
                case 'UKF'
                    h=findall(app.BreveExplicacaoFiltroTextArea,'Type','UITextArea');
                    for i=1:numel(h)
                        h(i).Value='';
                    end
                    app.BreveExplicacaoFiltroTextArea.Value = ['o UKF é usado para sistemas ' ...
                    ' não-lineares e com funções contínuas no tempo.'];
            end
            
        end

        % Button pushed function: ContinuarEscolhaFiltroButton
        function ContinuarEscolhaFiltroButtonPushed(app, event)
            switch (app.EscolhaFiltroDropDown.Value)
                case 'KF'
                    if (app.oLinear == 1)
                        app.UIFigure.Visible = 'on';
                        switch(app.EscolhaModeloDropDown.Value)
                            case 'Circuito RLC'
                                app.kalmanfilter_rlc = kalmanfilter_rlc(app, app.EscolhaModeloDropDown.Value);
                            case 'Sistema Massa-Mola'
                                app.kalmanfilter_smm = kalmanfilter_smm(app, app.EscolhaModeloDropDown.Value);
                        end
     
                    else 
                        waitfor(msgbox("O KF não pode ser usado para modelos não-lineares, escolha o EKF, UKF ou PF.", "modal"));
                    end
                    
                case 'EKF'
                    if (app.oLinear == 0)
                        app.UIFigure.Visible = 'on';
                        app.extendedkalmanfilter_kst = extendedkalmanfilter_kst(app,  app.EscolhaModeloDropDown.Value); 
                    else 
                        waitfor(msgbox("O EKF não é recomendado para ser usado para modelos lineares, escolha o KF.", "modal"));
                    end

                 case 'UKF'
                    if (app.oLinear == 0)
                        app.UIFigure.Visible = 'on';
                        app.unscentedkalmanfilter_kst = unscentedkalmanfilter_kst(app,  app.EscolhaModeloDropDown.Value); 
                    else 
                        waitfor(msgbox("O UKF não é recomendado para ser usado para modelos lineares, escolha o KF.", "modal"));
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BemVindoTab = uitab(app.TabGroup);
            app.BemVindoTab.Title = 'Bem-vindo(a)';
            app.ImportanteLabel = uilabel(app.BemVindoTab);
            app.ImportanteLabel.Text = 'Importante:';
            app.ImportanteTextArea = uitextarea(app.BemVindoTab);
            app.ImportanteTextArea.Value = {'Esta aplicação tem por objetivo, facilitar o aprendizado da disciplina de Introdução a Filtragem Estocástica e por isso é importante usá-la sob orientação do professor.'; ''; 'Ao perceber um problema, por favor, relatá-lo detalhadamente para o email: kfappdesigner@gmail.com, assim você estará contribuindo com o desenvolvimento e melhorias do programa. '};
            app.LiCienteCheckBox = uicheckbox(app.BemVindoTab);
            app.LiCienteCheckBox.Text = 'Li e estou ciente';
            app.Continuar1stPageButton = uibutton(app.BemVindoTab, 'push');
            app.Continuar1stPageButton.ButtonPushedFcn = createCallbackFcn(app, @Continuar1stPageButtonPushed, true);
            app.Continuar1stPageButton.Text = 'Continuar';
            app.EscolhaModeloTab = uitab(app.TabGroup);
            app.EscolhaModeloTab.Title = 'Escolha do Modelo';
            app.EscolhaoexemploaseranalisadoDropDownLabel = uilabel(app.EscolhaModeloTab);
            app.EscolhaoexemploaseranalisadoDropDownLabel.Text = 'Escolha o exemplo a ser analisado';
            app.EscolhaModeloDropDown = uidropdown(app.EscolhaModeloTab);
            app.EscolhaModeloDropDown.Items = {'-', 'Circuito RLC', 'Sistema Massa-Mola', 'Kinematic Single-Track'};
            app.EscolhaModeloDropDown.ValueChangedFcn = createCallbackFcn(app, @EscolhaModeloDropDownValueChanged, true);
            app.EscolhaModeloDropDown.Value = '-';
            app.BreveexplicaoTextAreaLabel = uilabel(app.EscolhaModeloTab);
            app.BreveexplicaoTextAreaLabel.Text = 'Breve explicação:';
            app.BreveExplicacaoModeloTextArea = uitextarea(app.EscolhaModeloTab);
            app.BreveExplicacaoModeloTextArea.Value = {'-'};
            app.ContinuarEscolhaModeloButton = uibutton(app.EscolhaModeloTab, 'push');
            app.ContinuarEscolhaModeloButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarEscolhaModeloButtonPushed, true);
            app.ContinuarEscolhaModeloButton.Text = 'Continuar';
            app.VoltarEscolhaModeloButton = uibutton(app.EscolhaModeloTab, 'push');
            app.VoltarEscolhaModeloButton.ButtonPushedFcn = createCallbackFcn(app, @VoltarEscolhaModeloButtonPushed, true);
            app.VoltarEscolhaModeloButton.Text = 'Voltar';
            app.EscolhaFiltroTab = uitab(app.TabGroup);
            app.EscolhaFiltroTab.Title = 'Escolha do Filtro';
            app.ContinuarEscolhaFiltroButton = uibutton(app.EscolhaFiltroTab, 'push');
            app.ContinuarEscolhaFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuarEscolhaFiltroButtonPushed, true);
            app.ContinuarEscolhaFiltroButton.Text = 'Continuar';
            app.VoltarEscolhaFiltroButton = uibutton(app.EscolhaFiltroTab, 'push');
            app.VoltarEscolhaFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @VoltarEscolhaFiltroButtonPushed, true);
            app.VoltarEscolhaFiltroButton.Text = 'Voltar';
            app.BreveexplicaoTextAreaLabel_2 = uilabel(app.EscolhaFiltroTab);
            app.BreveexplicaoTextAreaLabel_2.Text = 'Breve explicação:';
            app.BreveExplicacaoFiltroTextArea = uitextarea(app.EscolhaFiltroTab);
            app.BreveExplicacaoFiltroTextArea.Value = {'-'};
            app.EscolhadoFiltroLabel = uilabel(app.EscolhaFiltroTab);
            app.EscolhadoFiltroLabel.Text = 'Escolha do Filtro';
            app.EscolhaFiltroDropDown = uidropdown(app.EscolhaFiltroTab);
            app.EscolhaFiltroDropDown.Items = {'-', 'KF', 'EKF', 'UKF'};
            app.EscolhaFiltroDropDown.ValueChangedFcn = createCallbackFcn(app, @EscolhaFiltroDropDownValueChanged, true);
            app.EscolhaFiltroDropDown.Value = '-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end