classdef unscentedkalmanfilter_kst < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        UKFSetupTab            matlab.ui.container.Tab
        InstruesTextArea       matlab.ui.control.TextArea
        InstruesTextAreaLabel  matlab.ui.control.Label
        QSliderLabel           matlab.ui.control.Label
        QSlider                matlab.ui.control.Slider
        RSlider_2Label         matlab.ui.control.Label
        RSlider                matlab.ui.control.Slider
        PlotButton             matlab.ui.control.Button
        UIAxes_traj            matlab.ui.control.UIAxes
        UIAxes_state4          matlab.ui.control.UIAxes
        UIAxes_state3          matlab.ui.control.UIAxes
        UIAxes_state2          matlab.ui.control.UIAxes
        UIAxes_state1          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingMenu % Caller function --> menu
        dynamicModel % Model used in Kalman filter
        x = [];  % Estimation
        P = [];  % Prediction   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, menu, model)
            app.callingMenu = menu;
            app.dynamicModel = model;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
           cla(app.UIAxes_state1);
           cla(app.UIAxes_state2);
           cla(app.UIAxes_state3);
           cla(app.UIAxes_state4);
           cla(app.UIAxes_traj);
           run_ukf_kst(app.UIAxes_state1, app.UIAxes_state2, app.UIAxes_state3, app.UIAxes_state4, app.UIAxes_traj, app.RSlider.Value, app.QSlider.Value);
        end

        % Value changed function: RSlider
        function RSliderValueChanged(app, event)
            value = app.RSlider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % Override the selected value if you plan on using it within this function
            value = event.Source.MajorTicks(minIdx);
        end

        % Value changed function: QSlider
        function QSliderValueChanged(app, event)
            value = app.QSlider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % Override the selected value if you plan on using it within this function
            value = event.Source.MajorTicks(minIdx);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UKFSetupTab = uitab(app.TabGroup);
            app.UKFSetupTab.Title = 'UKF Setup';
            app.UIAxes_state1 = uiaxes(app.UKFSetupTab);
            title(app.UIAxes_state1, 'Estado: Posição em x')
            xlabel(app.UIAxes_state1, 'Tempo')
            ylabel(app.UIAxes_state1, 'x')
            zlabel(app.UIAxes_state1, 'Z')
            app.UIAxes_state2 = uiaxes(app.UKFSetupTab);
            title(app.UIAxes_state2, 'Estado: Posição em y')
            xlabel(app.UIAxes_state2, 'Tempo')
            ylabel(app.UIAxes_state2, 'y')
            zlabel(app.UIAxes_state2, 'Z')
            app.UIAxes_state3 = uiaxes(app.UKFSetupTab);
            title(app.UIAxes_state3, 'Estado: Ângulo de Steering')
            xlabel(app.UIAxes_state3, 'Tempo')
            ylabel(app.UIAxes_state3, 'delta')
            zlabel(app.UIAxes_state3, 'Z')
            app.UIAxes_state4 = uiaxes(app.UKFSetupTab);
            title(app.UIAxes_state4, 'Estado: Velocidade')
            xlabel(app.UIAxes_state4, 'Tempo')
            ylabel(app.UIAxes_state4, 'v')
            zlabel(app.UIAxes_state4, 'Z')
            app.UIAxes_traj = uiaxes(app.UKFSetupTab);
            title(app.UIAxes_traj, 'Estado: Trajetória')
            xlabel(app.UIAxes_traj, 'x')
            ylabel(app.UIAxes_traj, {'y'; ''})
            zlabel(app.UIAxes_traj, 'Z')
            app.PlotButton = uibutton(app.UKFSetupTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RSlider = uislider(app.UKFSetupTab);
            app.RSlider.Limits = [-3 3];
            app.RSlider.ValueChangedFcn = createCallbackFcn(app, @RSliderValueChanged, true);
            app.RSlider.MinorTicks = [-3 -2 -1 0 1 2 3];
            app.RSlider_2Label = uilabel(app.UKFSetupTab);
            app.RSlider_2Label.Text = 'R';
            app.QSlider = uislider(app.UKFSetupTab);
            app.QSlider.Limits = [-3 3];
            app.QSlider.ValueChangedFcn = createCallbackFcn(app, @QSliderValueChanged, true);
            app.QSlider.MinorTicks = [-3 -2 -1 0 1 2 3];
            app.QSliderLabel = uilabel(app.UKFSetupTab);
            app.QSliderLabel.Text = 'Q';
            app.InstruesTextAreaLabel = uilabel(app.UKFSetupTab);
            app.InstruesTextAreaLabel.Text = 'Instruções';
            app.InstruesTextArea = uitextarea(app.UKFSetupTab);
            app.InstruesTextArea.Editable = 'off';
            app.InstruesTextArea.Value = {'Escolha um fator r para para a covariância dos sensores e um valor q para a covariância de modelo de modo que:'; ''; 'R = 10^r*R_original'; 'Q = 10^q*Q_original'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = unscentedkalmanfilter_kst(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end